classdef LayeredMedia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        FiltrospticosdemediosmulticapaLayeredMediaLabel  matlab.ui.control.Label
        RenderizarButton            matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
        ButtonGroup                 matlab.ui.container.ButtonGroup
        BetaButton                  matlab.ui.control.RadioButton
        LambdaButton                matlab.ui.control.RadioButton
        BetaEditField               matlab.ui.control.NumericEditField
        LambdaEditField             matlab.ui.control.NumericEditField
        LongitudDesplegable         matlab.ui.control.DropDown
        inicialLabel                matlab.ui.control.Label
        LambdaDesplegable           matlab.ui.control.DropDown
        LongitudEditFieldLabel      matlab.ui.control.Label
        LongitudEditField           matlab.ui.control.NumericEditField
        AmbasButton                 matlab.ui.control.RadioButton
        finalLabel                  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        AyudaButton                 matlab.ui.control.Button
        CantidaddecapasSliderLabel  matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
        Medio1EditFieldLabel        matlab.ui.control.Label
        Medio1EditField             matlab.ui.control.NumericEditField
        Medio2EditFieldLabel        matlab.ui.control.Label
        Medio2EditField             matlab.ui.control.NumericEditField
        reaparapruebasymensajesTextAreaLabel  matlab.ui.control.Label
        Prueba                      matlab.ui.control.TextArea
    end


    methods (Access = private)

        % Button pushed function: RenderizarButton
        function RenderizarButtonPushed(app, event)
            medio1 = app.Medio1EditField.Value; % Recupera el valor de la impedancia intrínseca del medio 1
            medio2 = app.Medio2EditField.Value; % Recupera el valor de la impedancia intrínseca del medio 2
            etaEQ = 0+1i*0; % Variable que lleva control de cuánto es el valor de la capa equivalente anterior
            gamma = 0; % Valor de gamma
            Pr = 0; % Valor de potencia reflejada
            Pt = 0; % Valor de potencia transmitida
            salida = []; % Es una matriz que debe controlar quienes son los valores de salida [ gamma, Pr, Pt ]

            
            valor = app.Slider.Value; % Recupera el valor del slider
            valor = round(valor); % Lo redondea, ya que no hay capas con expansión decimal
            app.Slider.Value = valor; % Se lo asigna al Slider para que se note que está eligiendo un valor entero
            
            
%             if(app.ButtonGroup.SelectedObject == app.LambdaButton) % Si el usuario elige lambda, hace la conversión de unidades
%                 lambda = app.LambdaEditField.Value;
%                 opLambda = app.LambdaDesplegable.Value;
%                 switch opLambda % Se hace la conversión con las unidades
%                     case 'dm'
%                         bl = (lambda*0.1);   
%                     case 'cm'
%                         bl = (lambda*0.01);   
%                     case 'mm'
%                         bl = (lambda*0.001);
%                     case 'μm'
%                         bl = (lambda*0.000001);   
%                     case 'nm'
%                         bl = (lambda*0.000000001);  
%                     case 'pm'
%                         bl = (lambda*0.000000000001);
%                     otherwise
%                         bl = lambda;
%                 end
%                 
%             else % Si el usuario elige beta, lo convierte a lambda (ya que es la variable principal)
%                 
%                 bl = 2*pi/(app.BetaEditField.Value); % lambda = 2*pi/beta
%                 
%             end


            longitud = app.LongitudEditField.Value; % Se obtiene el valor de l
            l = longitud;
            opLongitud = app.LongitudDesplegable.Value;            
            switch opLongitud % Se hace la conversión con las unidades
                case 'dm'
                    longitud = (l*0.1); 
                case 'cm'
                    longitud = (l*0.01);  
                case 'mm'
                    longitud = (l*0.001);
                case 'μm'
                    longitud = (l*0.000001);   
                case 'nm'
                    longitud = (l*0.000000001);   
                case 'pm'
                    longitud = (l*0.000000000001);
            end
            
            p=mod(valor,2); % Variable que controla si está en una capa par o impar
            
            
            %%%%%%%%%%%%%%%%%%%%% Fin Declaración de variables %%%%%%%%%%%%%%%%%%%%%%%%%


            % Aquí van todos los mensajes en pantalla de: "Error capa 8" / "Error 404"
            % PE: medio1,medio2,longitud,bl > 0 


            %%%%%%%%%%%%%%%%%%%%%%%% Fin Manejo de excepciones %%%%%%%%%%%%%%%%%%%%%%%%%%%  
            
%             function y = funcion(x) % Pr focused
%                 
% %                 if n == 0
% %                     res = 1;
% %                 else
% %                     res = n*funciones(app,n-1);
% %                 end
% %                 action = medio2*(4+1j*tan((2*pi/x)*l))/(1+4j*tan((2*pi/x)*l));
% %                 action1 = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*medio1*tan(((2*pi)/x)*longitud)));
% %                 action2 = abs(action1);
% %                 y = action2;
%                 
% %                 if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
% %                     etaEQ = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*l))/(medio2+1j*medio1*tan(((2*pi)/x)*l)));
% %                     
% % %                     app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
% %                     app.Prueba.Value = "etaEQ: " + etaEQ;
% %                     
% %                 else % Es par (La primera es n1, y la última n2)
% %                     etaEQ = medio1*((medio2+1j*medio1*tan((2*pi/x)*longitud))/(medio1+1j*medio2*tan((2*pi/x)*longitud)));
% %                     
% %                     %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
% %                     app.Prueba.Value = "etaEQ: " + etaEQ;
% %                     
% %                 end
% %                 
% %                 gamma = (etaEQ-medio1)/(etaEQ+medio1);
% %                 Pr = abs(gamma)^2;
% %                 Pt = (1-(abs(gamma))^2);                
% %                 y = Pr;
% 
%                 if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
%                     etaEQ = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*medio1*tan(((2*pi)/x)*longitud)));
%                     
%                     %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
%                     
%                     
%                 else % Es par (La primera es n1, y la última n2)
%                     etaEQ = medio1*((medio2+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*medio2*tan(((2*pi)/x)*longitud)));
%                     
%                     %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
%                     %app.Prueba.Value = "etaEQ: " + etaEQ;
%                     
%                 end
%                 
%                 gamma = (medio2-medio1)/(medio2+medio1);
%                 Pr = gamma^2;
%                 Pt = (1-gamma^2);
%                 
%                 %salida = [salida;x,etaEQ,gamma,Pr,Pt]; % Solo lo uso como debugger
%                 
%                 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin Set-Up inicial %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 
%                 
%                 for n = (valor-3):-1:0 % Con esta parte, itero la ecuación del medio equivalente
%                     % Le resto 3 al valor, ya que en el setup inicial calculé para 2 medios
%                     
%                     if(n==0) % ¿Estamos simplificando las últimas 2 capas? Calcule los parámetros de interés
%                         gamma = (etaEQ-medio1)/(etaEQ+medio1);
%                         Pr = abs(gamma)^2;
%                         Pt = (1-abs(gamma)^2);
%                         %salida = [salida;x,etaEQ,gamma,Pr,Pt]; % Solo lo uso como debugger
%                         res = Pr;
%                     end
%                     
%                     if((p==1)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
%                         etaEQ = medio2*((etaEQ+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*etaEQ*tan(((2*pi)/x)*longitud)));
%                         p=0; % Por cada iteración cambio el valor de la paridad, para que entre al otro
%                     end
%                     if((p==0)&&(n~=0))
%                         etaEQ = medio1*((etaEQ+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*etaEQ*tan(((2*pi)/x)*longitud)));
%                         p=1; % Por cada iteración cambio el valor de la paridad, para que entre al otro
%                     end
%                 end
%                 
%                 p=mod(valor,2); % Variable que controla si está en una capa par o impar
%                 res = Pr;
%                 
%                 
%             end % Fin y = funcion(x)
%         
            function res = funcion(x) % Para poder ser usado por el fplot necesita tener solo una variable de entrada. 
                % La ventaja de tenerlo como método en este "scope" es que tiene acceso a todas las variables en este evento.
                
                if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
                    etaEQ = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*medio1*tan(((2*pi)/x)*longitud)));
                    
                    %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
            
                    
                else % Es par (La primera es n1, y la última n2)
                    etaEQ = medio1*((medio2+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*medio2*tan(((2*pi)/x)*longitud)));
                    
                    %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
                    %app.Prueba.Value = "etaEQ: " + etaEQ;
                    
                end
                
                gamma = (medio2-medio1)/(medio2+medio1);
                Pr = gamma^2;
                Pt = (1-gamma^2);
                
                %salida = [salida;x,etaEQ,gamma,Pr,Pt]; % Solo lo uso como debugger
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin Set-Up inicial %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                for n = (valor-3):-1:0 % Con esta parte, itero la ecuación del medio equivalente
                    % Le resto 3 al valor, ya que en el setup inicial calculé para 2 medios
                    
                    if(n==0) % ¿Estamos simplificando las últimas 2 capas? Calcule los parámetros de interés
                        gamma = (etaEQ-medio1)/(etaEQ+medio1);
                        Pr = abs(gamma)^2;
                        Pt = (1-abs(gamma)^2);
                        %salida = [salida;x,etaEQ,gamma,Pr,Pt]; % Solo lo uso como debugger
                        res = Pr;
                    end
                    
                    if((p==1)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
                        etaEQ = medio2*((etaEQ+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=0; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                    if((p==0)&&(n~=0))
                        etaEQ = medio1*((etaEQ+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=1; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                end
                
                p=mod(valor,2); % Variable que controla si está en una capa par o impar
                
                %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'             --------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+";     Pr: "+Pr+"; Pt: "+Pt;
                
                app.Prueba.Value = "medio1: "+medio1+          newline                      +"medio2: "+medio2+           newline                     +"Slider:"+valor+'                                      '+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+            newline      +          newline          +"etaEQ:"+etaEQ+   newline    +"Gamma: "+gamma+   newline     +"Pr: "+Pr+    newline   +"Pt: "+Pt;
                
                %app.Prueba.Value = "medio1: "+medio1+newline+"medio2: "+medio2+newline+"Slider:"+valor+newline'+"B/L:"+bl+newline+"Longitud:"+longitud+'; OpLong: '+opLongitud+ newline + newline +"etaEQ:"+etaEQ+"Gamma: "+gamma+mewline+"Pr: "+Pr+newline+"Pt: "+Pt;
                
                res = Pr;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin del núcleo multicapa %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            end
            
            
            function res = funciones(x) % Para poder ser usado por el fplot necesita tener solo una variable de entrada. 
                % La ventaja de tenerlo como método en este "scope" es que tiene acceso a todas las variables en este evento.
                
                if(p==1) %Si la cantidad de capas es impar, implica que la última es igual que la primera
                    etaEQ = medio2*((medio1+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*medio1*tan(((2*pi)/x)*longitud)));
                    
                    %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
            
                    
                else % Es par (La primera es n1, y la última n2)
                    etaEQ = medio1*((medio2+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*medio2*tan(((2*pi)/x)*longitud)));
                    
                    %app.Prueba.Value = "medio1: "+medio1+'                                '+"medio2: "+medio2+'                                '+"Slider:"+valor+'                                      '+"B/L:"+bl+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+'      ------------------------------------'+"etaEQ:"+etaEQ+"; Gamma: "+gamma+"; Pr: "+Pr+"; Pt: "+Pt;
                    %app.Prueba.Value = "etaEQ: " + etaEQ;
                    
                end
                
                gamma = (medio2-medio1)/(medio2+medio1);
                Pr = gamma^2;
                Pt = (1-gamma^2);
                
                %salida = [salida;x,etaEQ,gamma,Pr,Pt]; % Solo lo uso como debugger
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin Set-Up inicial %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                
                for n = (valor-3):-1:0 % Con esta parte, itero la ecuación del medio equivalente
                    % Le resto 3 al valor, ya que en el setup inicial calculé para 2 medios
                    
                    if(n==0) % ¿Estamos simplificando las últimas 2 capas? Calcule los parámetros de interés
                        gamma = (etaEQ-medio1)/(etaEQ+medio1);
                        Pr = abs(gamma)^2;
                        Pt = (1-abs(gamma)^2);
                        %salida = [salida;x,etaEQ,gamma,Pr,Pt]; % Solo lo uso como debugger
                        res = Pt;
                    end
                    
                    if((p==1)&&(n~=0)) % Si la cantidad de capas es < 3, no entra aquí, y no pasa nada.
                        etaEQ = medio2*((etaEQ+1j*medio2*tan(((2*pi)/x)*longitud))/(medio2+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=0; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                    if((p==0)&&(n~=0))
                        etaEQ = medio1*((etaEQ+1j*medio1*tan(((2*pi)/x)*longitud))/(medio1+1j*etaEQ*tan(((2*pi)/x)*longitud)));
                        p=1; % Por cada iteración cambio el valor de la paridad, para que entre al otro
                    end
                end
                
                p=mod(valor,2); % Variable que controla si está en una capa par o impar
                
                app.Prueba.Value = "medio1: "+medio1+          newline                      +"medio2: "+medio2+           newline                     +"Slider:"+valor+'                                      '+'                                            '+"Longitud:"+longitud+'; OpLong: '+opLongitud+            newline      +          newline          +"etaEQ:"+etaEQ+   newline    +"Gamma: "+gamma+   newline     +"Pr: "+Pr+    newline   +"Pt: "+Pt;
                    
                %app.Prueba.Value = "medio1: "+medio1+newline+"medio2: "+medio2+newline+"Slider:"+valor+newline'+"B/L:"+bl+newline+"Longitud:"+longitud+'; OpLong: '+opLongitud+ newline + newline +"etaEQ:"+etaEQ+"Gamma: "+gamma+mewline+"Pr: "+Pr+newline+"Pt: "+Pt;
                
                res = Pt;
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Fin del núcleo multicapa %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            end


            ax = app.UIAxes;
            f = @funciones; % Pt focused
            f2 = @funcion; % Pr focused
            
%             grid(ax,'on') % Encender los ejes
%             fplot(ax,f,[1 1500],'LineWidth',0.75);
%             hold(app.UIAxes,'on');
%             fplot(ax,f2,[1 1500],'LineWidth',0.75);
%             hold(app.UIAxes,'off');

            beta = app.BetaEditField.Value; %Lambda_inicial
            lambda = app.LambdaEditField.Value; %Lambda_final
            
            if(app.ButtonGroup.SelectedObject == app.LambdaButton)
                
                grid(ax,'on') % Encender los ejes
                fplot(ax,f,[beta lambda],'Color','b','LineWidth',0.75);
            
            elseif(app.ButtonGroup.SelectedObject == app.BetaButton)
               
                grid(ax,'on') % Encender los ejes
                fplot(ax,f2,[beta lambda],'Color','r','LineWidth',0.75);
                
            else
                
                grid(ax,'on') % Encender los ejes
                fplot(ax,f,[beta lambda],'Color','b','LineWidth',0.75);
                hold(app.UIAxes,'on');
                fplot(ax,f2,[beta lambda],'Color','r','LineWidth',0.75);
                hold(app.UIAxes,'off');
                
            end


%             rango = (1:0.1:15)';
%             %x = linspace(0,10,150);
%             f2 = funciones(rango);
%             grid;
%             plot(rango,f2,'Color',[0,0.7,0.9])
%             %plot(rango,f2,'Color','r','LineStyle','-')
%             grid;


            %%%%%%%%%%%%%%%%%%%%%%%% Fin del módulo de graficado %%%%%%%%%%%%%%%%%%%%%%
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
%             selection = questdlg('¿Está seguro que desea cerrar el programa?', 'Cerrar el programa', 'Sí', 'No', 'No');
%             switch selection
%                 case 'Sí'
%                     delete(app)
%                     
%                 case 'No'
%                     return
%             end
%             delete(app)
            delete(app)
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            f = msgbox({'Vieras que aaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaa' ; 'AAAAAAAAAAAAAAAAAAAAAAAAAAAAAAAAAAAAA'},'Aiudaaaaaaaaaaaa','non-modal');
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            valor = app.Slider.Value;
            valor = round(valor); % Lo redondea, ya que no hay capas con expansión decimal
            app.Slider.Value = valor; % Se lo asigna al Slider para que se note que está eligiendo un valor entero
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = ' ';
            app.FiltrospticosdemediosmulticapaLayeredMediaLabel = uilabel(app.Panel);
            app.FiltrospticosdemediosmulticapaLayeredMediaLabel.Text = 'Filtros ópticos de medios multicapa [ Layered Media ]';
            app.RenderizarButton = uibutton(app.Panel, 'push');
            app.RenderizarButton.ButtonPushedFcn = createCallbackFcn(app, @RenderizarButtonPushed, true);
            app.RenderizarButton.Text = 'Renderizar';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Espectro de Potencia')
            xlabel(app.UIAxes, '\lambda')
            ylabel(app.UIAxes, 'Pt [azul]      Pr [rojo]')
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.BetaButton = uiradiobutton(app.ButtonGroup);
            app.BetaButton.Text = 'Pr';
            app.LambdaButton = uiradiobutton(app.ButtonGroup);
            app.LambdaButton.Text = 'Pt';
            app.BetaEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.BetaEditField.Value = 1;
            app.LambdaEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.LambdaEditField.Value = 1500;
            app.LongitudDesplegable = uidropdown(app.ButtonGroup);
            app.LongitudDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LongitudDesplegable.Value = 'dm';
            app.inicialLabel = uilabel(app.ButtonGroup);
            app.inicialLabel.Text = 'λ_inicial';
            app.LambdaDesplegable = uidropdown(app.ButtonGroup);
            app.LambdaDesplegable.Items = {'m', 'dm', 'cm', 'mm', 'μm', 'nm', 'pm'};
            app.LambdaDesplegable.Visible = 'off';
            app.LambdaDesplegable.Value = 'mm';
            app.LongitudEditFieldLabel = uilabel(app.ButtonGroup);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.LongitudEditField.Value = 40;
            app.AmbasButton = uiradiobutton(app.ButtonGroup);
            app.AmbasButton.Text = 'Ambas';
            app.AmbasButton.Value = true;
            app.finalLabel = uilabel(app.ButtonGroup);
            app.finalLabel.Text = 'λ_final';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'Ω';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = 'Ω';
            app.AyudaButton = uibutton(app.Panel, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = '¿Ayuda?';
            app.CantidaddecapasSliderLabel = uilabel(app.Panel);
            app.CantidaddecapasSliderLabel.Text = {'Cantidad '; 'de capas'};
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [2 25];
            app.Slider.MajorTicks = [2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.MajorTickLabels = {'2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 2;
            app.Medio1EditFieldLabel = uilabel(app.Panel);
            app.Medio1EditFieldLabel.Text = 'Medio η1';
            app.Medio1EditField = uieditfield(app.Panel, 'numeric');
            app.Medio1EditField.Value = 343;
            app.Medio2EditFieldLabel = uilabel(app.Panel);
            app.Medio2EditFieldLabel.Text = 'Medio η2';
            app.Medio2EditField = uieditfield(app.Panel, 'numeric');
            app.Medio2EditField.Value = 2048;
            app.reaparapruebasymensajesTextAreaLabel = uilabel(app.Panel);
            app.reaparapruebasymensajesTextAreaLabel.Text = 'Área para pruebas y mensajes';
            app.Prueba = uitextarea(app.Panel);
            app.Prueba.Value = {'¿Qué hace falta?'; ''; '- Preguntar por lo del z=L'; ''; '- Arreglar bonita la ayuda'; ''; '- Arreglar el etaEQ dinámico'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = LayeredMedia

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end